classdef Transducer_sim < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        f_breakuphzEditField         matlab.ui.control.NumericEditField
        f_breakuphzEditFieldLabel    matlab.ui.control.Label
        FillingEditField             matlab.ui.control.NumericEditField
        FillingEditFieldLabel        matlab.ui.control.Label
        DdmmEditField                matlab.ui.control.NumericEditField
        DdmmEditFieldLabel           matlab.ui.control.Label
        f3hzEditField                matlab.ui.control.NumericEditField
        f3hzEditFieldLabel           matlab.ui.control.Label
        BLTmEditField                matlab.ui.control.NumericEditField
        BLTmEditFieldLabel           matlab.ui.control.Label
        BoxHeightmEditField          matlab.ui.control.NumericEditField
        BoxHeightmEditFieldLabel     matlab.ui.control.Label
        QMCEditField                 matlab.ui.control.NumericEditField
        QMCEditFieldLabel            matlab.ui.control.Label
        QECEditField                 matlab.ui.control.NumericEditField
        QECEditFieldLabel            matlab.ui.control.Label
        QTCEditField                 matlab.ui.control.NumericEditField
        QTCEditFieldLabel            matlab.ui.control.Label
        VbLEditField                 matlab.ui.control.NumericEditField
        VbLEditFieldLabel            matlab.ui.control.Label
        Sdcm2EditField               matlab.ui.control.NumericEditField
        Sdcm2EditFieldLabel          matlab.ui.control.Label
        fchzEditField                matlab.ui.control.NumericEditField
        fchzEditFieldLabel           matlab.ui.control.Label
        VabLEditField                matlab.ui.control.NumericEditField
        VabLEditFieldLabel           matlab.ui.control.Label
        MmdgEditField                matlab.ui.control.NumericEditField
        MmdgEditFieldLabel           matlab.ui.control.Label
        fuhzEditField                matlab.ui.control.NumericEditField
        fuhzEditFieldLabel           matlab.ui.control.Label
        BoxWidthmEditField           matlab.ui.control.NumericEditField
        BoxWidthmEditFieldLabel      matlab.ui.control.Label
        BoxLengthmEditField          matlab.ui.control.NumericEditField
        BoxLengthmEditFieldLabel     matlab.ui.control.Label
        Vdcm3EditField               matlab.ui.control.NumericEditField
        Vdcm3EditFieldLabel          matlab.ui.control.Label
        AcousticPowerEditField       matlab.ui.control.NumericEditField
        AcousticPowerEditFieldLabel  matlab.ui.control.Label
        EfficiencyEditField          matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel     matlab.ui.control.Label
        SensitivitydBEditField       matlab.ui.control.NumericEditField
        SensitivitydBEditFieldLabel  matlab.ui.control.Label
        QMSEditField                 matlab.ui.control.NumericEditField
        QMSEditFieldLabel            matlab.ui.control.Label
        QESEditField                 matlab.ui.control.NumericEditField
        QESEditFieldLabel            matlab.ui.control.Label
        ClearButton                  matlab.ui.control.Button
        CalculateButton              matlab.ui.control.Button
        fshzEditField                matlab.ui.control.NumericEditField
        fshzEditFieldLabel           matlab.ui.control.Label
        VasLEditField                matlab.ui.control.NumericEditField
        VasLEditFieldLabel           matlab.ui.control.Label
        LemHEditField                matlab.ui.control.NumericEditField
        LemHEditFieldLabel           matlab.ui.control.Label
        ReohmsEditField              matlab.ui.control.NumericEditField
        ReohmsEditFieldLabel         matlab.ui.control.Label
        XmaxmmEditField              matlab.ui.control.NumericEditField
        XmaxmmEditFieldLabel         matlab.ui.control.Label
        RmsNsmEditField              matlab.ui.control.NumericEditField
        RmsNsmEditFieldLabel         matlab.ui.control.Label
        CmsmmNEditField              matlab.ui.control.NumericEditField
        CmsLabel                     matlab.ui.control.Label
        MmsgEditField                matlab.ui.control.NumericEditField
        MmsgEditFieldLabel           matlab.ui.control.Label
        QTSEditField                 matlab.ui.control.NumericEditField
        QTSEditFieldLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function Calculate(comp, event)
            rho0 = 1.2;
            c = 345; %m/s
            pref = 20e-6;

            QES = comp.QESEditField.Value;
            QMS = comp.QMSEditField.Value;
            QTS = comp.QTSEditField.Value;
            QTC = comp.QTCEditField.Value;
            QEC = comp.QECEditField.Value;
            QMC = comp.QMCEditField.Value;
            Xmax = comp.XmaxmmEditField.Value; %mm
            Sd = comp.Sdcm2EditField.Value; %cm^2
            Mms = comp.MmsgEditField.Value; %Diaphragm Mass (g)
            Cms = comp.CmsmmNEditField.Value; %Mechanical Compliance of Suspension (mm/N)
            Rms = comp.RmsNsmEditField.Value; %N*s/m
            Re = comp.ReohmsEditField.Value; % DC resistance (ohms)
            Le = comp.LemHEditField.Value; % Coil Resistance (mH)
            Vas = comp.VasLEditField.Value; %L
            fs = comp.fshzEditField.Value; %resonant frequency (hz)
            Vd = comp.Vdcm3EditField.Value; %volume driver cm^3;
            Vab = comp.VabLEditField.Value; %L
            Mmd = comp.MmdgEditField.Value;%mechanical mass diaphragm (g)
            BL = comp.BLTmEditField.Value; %Tm
            Vb = comp.VbLEditField.Value;  % Total Volume (L)
            Dd = comp.DdmmEditField.Value; % Driver Diameter (mm)


            req_params = {Vd,Vb,BL,Re,Le};
            names = ["Vd","Vb","BL","Re","Le"];
            
            fig = ancestor(comp,'figure');

            for i = 1:length(req_params)
                value = req_params{i};
                if value == 0                   
                    uialert(fig,names(i) + " is a required input.","Missing Input");
                    return;
                end
            end

            if Vas == 0 && Cms == 0                
                uialert(fig, "Please enter a value for Vas or Cms.","Missing Input");
                return;
            end
            
            
            if Mms == 0 && Mmd == 0
                uialert(fig, "Please enter a value for Mmd or Mms.","Missing Input");
                return;
            end
            
            if Sd == 0 && Dd == 0
                uialert(fig, "Please enter a value for Sd or Dd.","Missing Input");
                return;
            end
            
            if Rms == 0 && QMS == 0
                uialert(fig, "Please enter a value for Rms or QMS.","Missing Input");
                return;
            end

            %convert to SI
            Dd_m = Dd / 1000;
            Sd_m2  = Sd / 1e4;     % cm^2 to m^2
            Mms_kg = Mms / 1000;   % g to kg
            Mmd_kg = Mmd / 1000; %g to kg
            Cms_m = Cms / 1000;   % mm/N to m/N
            Vas_m3 = Vas / 1000;   % L to m^3
            Vb_m3  = Vb  / 1000;   % L to m^3            
            Vd_m3  = Vd  / 1e6;    % cm^3 to m^3
            Le_H  = Le  / 1000;   % mH to H
                        
            
            if Sd == 0
                    a = Dd_m/2; % radius m
                    Sd_m2 = pi*a^2; %m^2
                    Sd_cm2 = Sd_m2*1e4; %cm^2
                    comp.Sdcm2EditField.Value = Sd_cm2;
            end
    
            if Dd == 0
                a = sqrt(Sd_m2/pi); %radius (m) 
                Dd_m = a*2; %diameter (m)
                Dd_mm = Dd_m*1000; %mm
                comp.DdmmEditField.Value = Dd_mm;    
            end

            %Golden Ratio Dimension Calculations
            V = (Vb_m3)^(1/3);
            comp.BoxWidthmEditField.Value = V; %m
            comp.BoxHeightmEditField.Value = 1.618*V; %m
            comp.BoxLengthmEditField.Value = 0.618*V; %m
                      
            a = sqrt(Sd_m2/pi); %radius (m)
            Ma = (8*rho0)/(3*a*pi^2); %kg/m^4           
                  
            for k = 1:3               
                                             
                
                if  Xmax == 0
                    Xmax = Vd_m3/Sd_m2; %m
                    Xmax_mm = Xmax*1000; %mm
                    comp.XmaxmmEditField.Value = Xmax_mm;
                end

                if Vas ~= 0 && Cms == 0                   
                    Cms_m = (Vas_m3 /(rho0*c^2)) * (1/Sd_m2^2); %m/N
                    Cms_mm = Cms_m*1000; %mm/N
                    comp.CmsmmNEditField.Value = Cms_mm;
                end

                
                if Cms ~= 0 && Vas == 0                   
                    Vas_m3 = Cms_m * rho0 * c^2 * Sd_m2^2; %m^3
                    Vas = Vas_m3*1000; % L
                    comp.VasLEditField.Value = Vas;
                end

                %Vas and Cms guaranteed to have a value
                 
                if  Mmd == 0 && Mms ~= 0
                    Mmd_kg = Mms_kg - 2*(Sd_m2^2)*Ma;%kg
                    Mmd = Mmd_kg*1000; %g
                    comp.MmdgEditField.Value = Mmd;
                end

                if Mms == 0 && Mmd ~= 0
                    Mms_kg = Mmd_kg + 2*(Sd_m2^2)* Ma; %kg
                    Mms = Mms_kg * 1000; %g
                    comp.MmsgEditField.Value = Mms;
                end
                
                %Mmd and Mms guaranteed to have a value                            

                if Vab == 0                   
                    Vab_m3 = Vb_m3 - Vd_m3; %m^3
                    Vab = Vab_m3*1000; %L
                    comp.VabLEditField.Value = Vab;                    
                end
    
                %guaranteed to have alpha
                alpha = Vas/Vab;

                if  Rms == 0 && QMS ~= 0
                    Rms = sqrt(Mms_kg/Cms_m) * (1/QMS);                 
                    comp.RmsNsmEditField.Value = Rms;                   
                end

                if QMS == 0 && Rms ~= 0
                    QMS = sqrt(Mms_kg/Cms_m)*(1/Rms);
                    comp.QMSEditField.Value = QMS;
                end

                %QMS and Rms guaranteed to have a value

                if  fs == 0
                    fs = 1 / (2*pi*sqrt(Mms_kg*Cms_m));
                    comp.fshzEditField.Value = fs;
                end
    
                if  QES == 0
                    QES = (Re/BL^2)*sqrt(Mms_kg/Cms_m);
                    comp.QESEditField.Value = QES;
                end   
                                                                            
   
                if  QTS == 0
                    QTS = (QMS*QES)/(QMS+QES);
                    comp.QTSEditField.Value = QTS;                
                end
    
                if  QEC == 0
                    QEC = sqrt(1+alpha)*QES;
                    comp.QECEditField.Value = QEC;
                end
    
                if  QMC == 0 
                    QMC = sqrt(1+alpha)*QMS;
                    comp.QMCEditField.Value = QMC;
                end
    
                if  QTC == 0 
                    QTC = sqrt(1+alpha)*QTS;
                    comp.QTCEditField.Value = QTC;                                 
                end
                                                                
            end
            

            %outputs + Plots 

            %clear plots
            yyaxis(comp.UIAxes, 'left');
            cla(comp.UIAxes);
            yyaxis(comp.UIAxes, 'right');
            cla(comp.UIAxes);
            yyaxis(comp.UIAxes, 'left');

            f = 20:20000; %frequency range
            
            w = 2*pi*f; 
            s = 1i*w;
            filling = comp.FillingEditField.Value/100;           
            rho_f = 2400; % density of fiberglass filling (kg/m3) 
            rho_stuffing = rho0*(1-filling)+ (rho_f*filling);
            B = 0.65; %mass loading factor
            Mas = Mms_kg/Sd_m2^2; %kg/m^2
            Mab = (B*rho_stuffing)/(pi*a);
            Mac = Mas + Mab;
            Mad = Mmd_kg/Sd_m2^2;
            
            %Rae = (BL)^2 / (Re*Sd_m2^2);
            %Ras = Rms/Sd_m2^2;
            %Rat = Rae+Ras;
                        
            %fc
            Cas = Cms_m*Sd_m2^2;
            Cat = Cas/(1+alpha);
            fc = 1 / (2*pi*sqrt(Mac*Cat));
            comp.fchzEditField.Value = fc;

            %f3
            x = 1/(2*QTC^2)-1;
            f_3 = fc * sqrt(x + sqrt(x^2+1)) ;
            comp.f3hzEditField.Value = f_3;

            %fu
            fu1 = (Re*Mms_kg)/(2*pi*Mmd_kg*Le_H);
            fu2 = ((sqrt(2)*c)/a)/(2*pi);
            fu = min(fu1,fu2);
            comp.fuhzEditField.Value = fu;
            comp.f_breakuphzEditField.Value = fu2;
        
            %efficiency
            Vat_m3 = Vas_m3/(1+alpha); %m^3
            N0 = (4*pi^2/c^3) * (Vat_m3*fc^3/QEC);
            comp.EfficiencyEditField.Value = N0;

            %Acoustic Power
            PAR = ((4*rho0*pi^3*fc^4)/c) * Vd_m3^2 * ((QTC^2 - 0.25)/QTC^4);
            comp.AcousticPowerEditField.Value = PAR;

            %Sensitivity
            psens = (sqrt(2*pi*rho0)/c) * fc^(3/2) * sqrt(Vat_m3/(Re*QEC));
            sensitivity = 20*log10(psens/pref);
            comp.SensitivitydBEditField.Value = sensitivity;
            
            wu = (Re*Mac) / (Mad*Le_H);
            LPF = 1./ (1+s/wu);
            wc = 2*pi*fc;
            HPF = (s/wc).^2 ./ ((s/wc).^2 + (1/QTC)*(s/wc) + 1); 
            p = LPF.*HPF;
            %magnitude
            mag = sensitivity+(20*log10(abs(p))); %magnitude (dB)
            
            %impedance
            Rec = Re * (QMC/QEC);
            x1 = Re + s*Le_H;
            y1 = ((Rec/QMC)*(s/wc)) ./ ((s/wc).^2 + (1/QMC)*(s/wc) + 1);
            Zvc = x1+y1; 

            
            %plots            
            semilogx(comp.UIAxes, f,mag,'b');
            hold(comp.UIAxes,'on');         
            yyaxis(comp.UIAxes, 'right');
            comp.UIAxes.YAxis(2).Visible = 'on';
            semilogx(comp.UIAxes, f, abs(Zvc), 'r', 'LineWidth', 1);
            ylabel(comp.UIAxes, 'Impedance (Ohms)');
            xline(comp.UIAxes,fc,'--k','Closed Box Resonance (fc)',LabelHorizontalAlignment= 'right',LabelVerticalAlignment='bottom');
            xline(comp.UIAxes,fu,'-g', 'Upper Frequency Limit (fu)',LabelHorizontalAlignment= 'right', LabelVerticalAlignment='bottom');
            xline(comp.UIAxes,fu2,'-m','breakup',LabelHorizontalAlignment= 'right',LabelVerticalAlignment= 'bottom');
            comp.UIAxes.YLim = [0 120]; 
            comp.UIAxes.YTick = [0 10 20 30 40 50 60 70 80 90 100 110];
            comp.UIAxes.YTickLabel = {'0'; '10'; '20'; ...
                '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'; '110'};
            hold(comp.UIAxes,'off');

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(comp, event)
            comp.QESEditField.Value = 0;
            comp.QMSEditField.Value = 0;
            comp.QTSEditField.Value = 0;
            comp.QTCEditField.Value = 0;
            comp.QECEditField.Value = 0;
            comp.QMCEditField.Value = 0;
            comp.XmaxmmEditField.Value = 0; 
            comp.Sdcm2EditField.Value = 0; 
            comp.MmsgEditField.Value = 0; 
            comp.CmsmmNEditField.Value = 0; 
            comp.RmsNsmEditField.Value = 0;
            comp.ReohmsEditField.Value = 0; 
            comp.LemHEditField.Value = 0;
            comp.VasLEditField.Value = 0; 
            comp.fshzEditField.Value = 0; 
            comp.Vdcm3EditField.Value = 0; 
            comp.VabLEditField.Value = 0; 
            comp.MmdgEditField.Value = 0;
            comp.BLTmEditField.Value = 0; 
            comp.VbLEditField.Value = 0; 
            comp.f3hzEditField.Value = 0;
            comp.BoxLengthmEditField.Value = 0;
            comp.BoxHeightmEditField.Value = 0;
            comp.BoxWidthmEditField.Value = 0;
            comp.DdmmEditField.Value = 0;           
            comp.fuhzEditField.Value = 0;
            comp.SensitivitydBEditField.Value = 0;
            comp.FillingEditField.Value = 0;
            comp.AcousticPowerEditField.Value = 0;
            comp.EfficiencyEditField.Value = 0;
            comp.fchzEditField.Value = 0;
            comp.f_breakuphzEditField.Value = 0;

            %clear plots
            yyaxis(comp.UIAxes, 'left');
            cla(comp.UIAxes);
            yyaxis(comp.UIAxes, 'right');
            cla(comp.UIAxes);
            yyaxis(comp.UIAxes, 'left');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 982 669];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.UIAxes = uiaxes(comp);
            title(comp.UIAxes, {'Magnitude Response'; 'and Impedance'})
            xlabel(comp.UIAxes, 'Frequency (hz)')
            ylabel(comp.UIAxes, 'Magnitude (dB SPL)')
            comp.UIAxes.XLim = [20 20000];
            comp.UIAxes.YLim = [50 130];
            comp.UIAxes.XTick = [20 100 500 1000 10000 20000];
            comp.UIAxes.XScale = 'log';
            comp.UIAxes.XTickLabel = {'20'; '100'; '500'; '1k'; '10k'; '20k'};
            comp.UIAxes.YTick = [50 60 70 80 90 100 110 120];
            comp.UIAxes.YTickLabel = {'50'; '60'; '70'; '80'; '90'; '100'; '110'; '120'};
            comp.QTSEditFieldLabel = uilabel(comp);
            comp.QTSEditFieldLabel.Text = 'QTS';
            comp.QTSEditField = uieditfield(comp, 'numeric');
            comp.MmsgEditFieldLabel = uilabel(comp);
            comp.MmsgEditFieldLabel.Text = 'Mms  (g)';
            comp.MmsgEditField = uieditfield(comp, 'numeric');
            comp.MmsgEditField.Value = 82;
            comp.CmsLabel = uilabel(comp);
            comp.CmsLabel.Text = ' Cms (mm/N)';
            comp.CmsmmNEditField = uieditfield(comp, 'numeric');
            comp.RmsNsmEditFieldLabel = uilabel(comp);
            comp.RmsNsmEditFieldLabel.Text = 'Rms (N*s/m)';
            comp.RmsNsmEditField = uieditfield(comp, 'numeric');
            comp.XmaxmmEditFieldLabel = uilabel(comp);
            comp.XmaxmmEditFieldLabel.Text = 'Xmax (mm)';
            comp.XmaxmmEditField = uieditfield(comp, 'numeric');
            comp.ReohmsEditFieldLabel = uilabel(comp);
            comp.ReohmsEditFieldLabel.Text = 'Re (ohms)';
            comp.ReohmsEditField = uieditfield(comp, 'numeric');
            comp.ReohmsEditField.Value = 3.9;
            comp.LemHEditFieldLabel = uilabel(comp);
            comp.LemHEditFieldLabel.Text = 'Le (mH)';
            comp.LemHEditField = uieditfield(comp, 'numeric');
            comp.LemHEditField.Value = 0.75;
            comp.VasLEditFieldLabel = uilabel(comp);
            comp.VasLEditFieldLabel.Text = 'Vas (L)';
            comp.VasLEditField = uieditfield(comp, 'numeric');
            comp.VasLEditField.Value = 8.4;
            comp.fshzEditFieldLabel = uilabel(comp);
            comp.fshzEditFieldLabel.Text = 'fs (hz)';
            comp.fshzEditField = uieditfield(comp, 'numeric');
            comp.CalculateButton = uibutton(comp, 'push');
            comp.CalculateButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Calculate, true);
            comp.CalculateButton.Text = 'Calculate';
            comp.ClearButton = uibutton(comp, 'push');
            comp.ClearButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ClearButtonPushed, true);
            comp.ClearButton.Text = 'Clear';
            comp.QESEditFieldLabel = uilabel(comp);
            comp.QESEditFieldLabel.Text = 'QES';
            comp.QESEditField = uieditfield(comp, 'numeric');
            comp.QMSEditFieldLabel = uilabel(comp);
            comp.QMSEditFieldLabel.Text = 'QMS';
            comp.QMSEditField = uieditfield(comp, 'numeric');
            comp.QMSEditField.Value = 3.1;
            comp.SensitivitydBEditFieldLabel = uilabel(comp);
            comp.SensitivitydBEditFieldLabel.Text = 'Sensitivity (dB)';
            comp.SensitivitydBEditField = uieditfield(comp, 'numeric');
            comp.EfficiencyEditFieldLabel = uilabel(comp);
            comp.EfficiencyEditFieldLabel.Text = 'Efficiency (%)';
            comp.EfficiencyEditField = uieditfield(comp, 'numeric');
            comp.AcousticPowerEditFieldLabel = uilabel(comp);
            comp.AcousticPowerEditFieldLabel.Text = 'Acoustic Power';
            comp.AcousticPowerEditField = uieditfield(comp, 'numeric');
            comp.Vdcm3EditFieldLabel = uilabel(comp);
            comp.Vdcm3EditFieldLabel.Text = 'Vd (cm^3)';
            comp.Vdcm3EditField = uieditfield(comp, 'numeric');
            comp.Vdcm3EditField.Value = 360;
            comp.BoxLengthmEditFieldLabel = uilabel(comp);
            comp.BoxLengthmEditFieldLabel.Text = 'Box Length (m)';
            comp.BoxLengthmEditField = uieditfield(comp, 'numeric');
            comp.BoxLengthmEditField.Editable = 'off';
            comp.BoxWidthmEditFieldLabel = uilabel(comp);
            comp.BoxWidthmEditFieldLabel.Text = 'Box Width (m)';
            comp.BoxWidthmEditField = uieditfield(comp, 'numeric');
            comp.BoxWidthmEditField.Editable = 'off';
            comp.fuhzEditFieldLabel = uilabel(comp);
            comp.fuhzEditFieldLabel.Text = 'fu (hz)';
            comp.fuhzEditField = uieditfield(comp, 'numeric');
            comp.MmdgEditFieldLabel = uilabel(comp);
            comp.MmdgEditFieldLabel.Text = 'Mmd (g)';
            comp.MmdgEditField = uieditfield(comp, 'numeric');
            comp.VabLEditFieldLabel = uilabel(comp);
            comp.VabLEditFieldLabel.Text = 'Vab (L)';
            comp.VabLEditField = uieditfield(comp, 'numeric');
            comp.VabLEditField.Editable = 'off';
            comp.fchzEditFieldLabel = uilabel(comp);
            comp.fchzEditFieldLabel.Text = 'fc (hz)';
            comp.fchzEditField = uieditfield(comp, 'numeric');
            comp.Sdcm2EditFieldLabel = uilabel(comp);
            comp.Sdcm2EditFieldLabel.Text = 'Sd (cm^2)';
            comp.Sdcm2EditField = uieditfield(comp, 'numeric');
            comp.Sdcm2EditField.Value = 201;
            comp.VbLEditFieldLabel = uilabel(comp);
            comp.VbLEditFieldLabel.Text = 'Vb (L)';
            comp.VbLEditField = uieditfield(comp, 'numeric');
            comp.VbLEditField.Value = 42.48;
            comp.QTCEditFieldLabel = uilabel(comp);
            comp.QTCEditFieldLabel.Text = 'QTC';
            comp.QTCEditField = uieditfield(comp, 'numeric');
            comp.QECEditFieldLabel = uilabel(comp);
            comp.QECEditFieldLabel.Text = 'QEC';
            comp.QECEditField = uieditfield(comp, 'numeric');
            comp.QMCEditFieldLabel = uilabel(comp);
            comp.QMCEditFieldLabel.Text = 'QMC';
            comp.QMCEditField = uieditfield(comp, 'numeric');
            comp.BoxHeightmEditFieldLabel = uilabel(comp);
            comp.BoxHeightmEditFieldLabel.Text = 'Box Height (m)';
            comp.BoxHeightmEditField = uieditfield(comp, 'numeric');
            comp.BoxHeightmEditField.Editable = 'off';
            comp.BLTmEditFieldLabel = uilabel(comp);
            comp.BLTmEditFieldLabel.Text = 'BL (Tm)';
            comp.BLTmEditField = uieditfield(comp, 'numeric');
            comp.BLTmEditField.Value = 9.7;
            comp.f3hzEditFieldLabel = uilabel(comp);
            comp.f3hzEditFieldLabel.Text = 'f3 (hz)';
            comp.f3hzEditField = uieditfield(comp, 'numeric');
            comp.DdmmEditFieldLabel = uilabel(comp);
            comp.DdmmEditFieldLabel.Text = 'Dd (mm)';
            comp.DdmmEditField = uieditfield(comp, 'numeric');
            comp.FillingEditFieldLabel = uilabel(comp);
            comp.FillingEditFieldLabel.Text = 'Filling (%)';
            comp.FillingEditField = uieditfield(comp, 'numeric');
            comp.FillingEditField.Value = 0.25;
            comp.f_breakuphzEditFieldLabel = uilabel(comp);
            comp.f_breakuphzEditFieldLabel.Text = 'f_breakup (hz)';
            comp.f_breakuphzEditField = uieditfield(comp, 'numeric');
        end
    end
end